classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImagesUIFigure  matlab.ui.Figure
        BWaxes          matlab.ui.control.UIAxes
        OpenBut         matlab.ui.control.Button
        histBut         matlab.ui.control.Button
        OneImg1         matlab.ui.control.NumericEditField
        OneImg2         matlab.ui.control.NumericEditField
        Button_4        matlab.ui.control.Button
        Slider          matlab.ui.control.Slider
        Slider2         matlab.ui.control.Slider
        IMaxes          matlab.ui.control.UIAxes
        Button_5        matlab.ui.control.Button
        Label           matlab.ui.control.Label
        Button_6        matlab.ui.control.Button
        Label_4         matlab.ui.control.Label
        Label_5         matlab.ui.control.Label
        ManyImg1        matlab.ui.control.EditField
        ManyImg2        matlab.ui.control.EditField
        Label_6         matlab.ui.control.Label
        Plane           matlab.ui.control.UIAxes
        Label_8         matlab.ui.control.Label
        select          matlab.ui.control.Slider
        BWaxes_2        matlab.ui.control.UIAxes
        Gauss           matlab.ui.control.Button
        median          matlab.ui.control.Button
        Button_7        matlab.ui.control.Button
        Button_8        matlab.ui.control.Button
        Button_9        matlab.ui.control.Button
        Button_11       matlab.ui.control.Button
        Label_9         matlab.ui.control.Label
        Label_10        matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        Button_12       matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global data3d;
            global rgbIm;
            global grayIm;
            global selectedImage;
            selectedImage = app.select.Value;
            data = load("C:\practis IPF\OCT_full_data_new.mat");
            data3d = data.data3d;
            rgbIm = log(data3d(:,:,1));
            grayIm = rgbIm;
            imshow(grayIm, [0 10],  'Parent',app.BWaxes_2);
            imshow(rgbIm, [0 10],'Parent',app.IMaxes );
            imshow(grayIm, [0 10],  'Parent',app.BWaxes);
        end

        % Button pushed function: OpenBut
        function OpenButPushed(app, event)
            global data3d;
            global rgbIm;
            global grayIm;
            global h;
            global c;
            global gray;
            global selectedImage;
            selectedImage = app.select.Value;
            [filename, pathname] =  uigetfile('*.*', 'Open file');
            disp( [filename, pathname]);
            data = load([pathname, filename]);
            data3d = data.data3d;
            rgbIm = log(data3d(:,:,selectedImage));
            grayIm = rgbIm;
            imshow(grayIm, [0 10],  'Parent',app.BWaxes_2);
            
            % grayIm=edge(grayIm, 'sobel', 5);
            
            %image(squeeze(data3d(128,:,:)));
            %data3d = 10*log10(squeeze(data3d(128,:,:)));
            %rgbIm = imread(strcat(pathname,filename));
            
            
            imshow(rgbIm, [0 10],'Parent',app.IMaxes );
            imshow(grayIm, [0 10],  'Parent',app.BWaxes);
            
            
        end

        % Button pushed function: histBut
        function histButPushed(app, event)
            global rgbIm;
            c = colormap('hot');
            gray = rgb2gray(c);
            imhist(rgbIm, [0, 10]);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            global binIm;
            edema = 0;
            noEdema = 0;
            procent = 0;
            [n,m] = size(binIm);
            for i=1:n
                for s=1:m
                    if binIm(i,s) == 1
                        noEdema = noEdema + 1;
                    else
                        edema = edema + 1;
                    end
                end
            end
            procent = (noEdema/(n*m))*100;
            app.Label.Text = num2str(procent);
        end

        % Value changed function: Slider, Slider2
        function SliderValueChanged(app, event)
            global grayIm;
            global binIm;
            global value1;
            global value2;
            global selectedImage;
            value1 = app.Slider.Value;
            value2 = app.Slider2.Value;
            binIm = roicolor(grayIm, value1, value2);
            imshow(binIm, 'Parent',app.BWaxes);
            app.OneImg1.Value = value1;
            app.OneImg2.Value = value2;
            [n,m] = size(binIm);
            for i=2:n-1
                for s=2:m-1
                    if (binIm(i, s) == 1 && (binIm(i+1,s) == 0 ||  binIm(i-1,s) == 0))
                        planeIm(i, s) =1;
                    else
                        planeIm(i, s) = 0;
                        
                    end
                end
            end
            imshow(planeIm(:,:, selectedImage), 'Parent', app.BWaxes_2);
        end

        % Value changed function: OneImg1, OneImg2
        function EditFieldValueChanged(app, event)
            global grayIm;
            global rgbIm;
            global planeIm;
            global binIm;
            global value1;
            global value2;
            value1 = app.OneImg1.Value;
            value2 = app.OneImg2.Value;
            binIm = roicolor(grayIm, value1, value2);
            imshow(binIm, 'Parent',app.BWaxes);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            global rgbIm;
            global grayIm;
            rgbIm=imcrop(rgbIm);
            grayIm = rgb2gray(rgbIm);
            imshow(rgbIm, 'Parent',app.IMaxes);
            imshow(grayIm, 'Parent',app.BWaxes);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            global data3d;
            global grayIm;
            global planeIm;
            global selectedImage;
            app.Label.Text = "Start";
            [~, ~, numOfImages] = size(data3d)
            for j=1:numOfImages
                grayIm = log(data3d(:,:,j));
                grayIm = imgaussfilt(grayIm,3);
                grayIm = imgaussfilt(grayIm,3);
                f=fspecial('sobel');
                grayIm=imfilter(grayIm,f);
                binIm = roicolor(grayIm, 0.85, 15);
                [n,m] = size(binIm);
                for i=2:n-1
                    for s=2:m-1
                        if (binIm(i, s) == 1 && (binIm(i+1,s) == 0 ||  binIm(i-1,s) == 0))
                            planeIm(i, s, j) = 1;
                        else
                            planeIm(i, s, j) = 0;
                        end
                    end
                end
            end
            app.Label.Text = 'End';
            imshow(planeIm(:,:, selectedImage), 'Parent', app.Plane);
        end

        % Value changed function: select
        function selectValueChanged(app, event)
            value = app.select.Value;
            global planeIm;
            global data3d;
            Im = log(data3d(:,:,fix(value)));
            imshow(planeIm(:,:,fix(value)), 'Parent', app.Plane);
            imshow(Im, [0 10],  'Parent',app.BWaxes_2);
            
        end

        % Button pushed function: Gauss
        function GaussButtonPushed(app, event)
            global grayIm;
            grayIm = imgaussfilt(grayIm,3);
            imshow(grayIm, [0 10],  'Parent',app.IMaxes);
            
        end

        % Button pushed function: median
        function medianButtonPushed(app, event)
            global grayIm;
            grayIm=medfilt2(grayIm);
            imshow(grayIm, [0 10],  'Parent',app.IMaxes);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            global grayIm;
            global rgbIm;
            grayIm = rgbIm;
            imshow(grayIm, [0 10],  'Parent',app.IMaxes);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            global grayIm;
            f=fspecial('sobel');
            grayIm=imfilter(grayIm,f);
            imshow(grayIm, [0 10],  'Parent',app.IMaxes);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            global grayIm;
            se=strel('disk',3); % Создание морфологического структурного элемента (STREL)- массив 0 и 1 с расположением единиц в виде круга, эллипса, квадрата
            grayIm=imdilate(grayIm,se); % Размытие изображени
            imshow(grayIm, [0 10],  'Parent',app.IMaxes);
        end

        % Callback function
        function Button_10Pushed(app, event)
            global grayIm;
            grayIm=edge(grayIm, 'sobel', 5);
            
        end

        % Value changing function: select
        function selectValueChanging(app, event)
            value = event.Value;
            global planeIm;
            global data3d;
            Im = log(data3d(:,:,fix(value)));
            imshow(planeIm(:,:,fix(value)), 'Parent', app.Plane);
            imshow(Im, [0 10],  'Parent',app.BWaxes_2);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            global data3d;
            global grayIm;
            global planeIm;
            global selected0Image;
            global isFirstLayer;
            isFirstLayer = true;
            app.Label.Text = "Start";
            [~, ~, numOfImages] = size(data3d)
            for j=1:numOfImages
                grayIm = log(data3d(:,:,j));
                se=strel('disk',3); % Создание морфологического структурного элемента (STREL)- массив 0 и 1 с расположением единиц в виде круга, эллипса, квадрата
                 grayIm=imdilate(grayIm,se); % Размытие изображени
                grayIm = imgaussfilt(grayIm,3);
                
                binIm = roicolor(grayIm, 7.2, 8.2);
                [n,m] = size(binIm);
                for i=2:n-1
                    for s=2:m-1
                        if (binIm(i+1,s) == 1 && binIm(i-1,s) == 0)
                            planeIm(i, s, j) = 1;
                        else
                            planeIm(i, s, j) = 0;
                        end
                    end
                end
            end
            app.Label.Text = 'End';
            imshow(planeIm(:,:, 1), 'Parent', app.Plane);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
             global planeIm;
             global img3d;
          X=[];
          Y=[];
          Z=[];
          w=1;
          v=1;
             [x, y, z] = size(planeIm)
             Z1 = []
            for j=1:50
                for i=2:x-1
                    for s=2:y-1
                        if planeIm(i, s, j) == 1
                            X  = [X x-i-1];
                            Y  = [Y j];
                            Z  = [Z s];
                            Z1(w,v)=i;
                            v=v+1
                        else
                           
                           continue;
                        end
                    end
                end
                w=w+1;
            end
plot3(Y,Z,X, '*');    
surf(Y,Z,Z1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImagesUIFigure = uifigure;
            app.ImagesUIFigure.Name = 'Images';
            app.BWaxes = uiaxes(app.ImagesUIFigure);
            title(app.BWaxes, 'Бинарное Изображение')
            app.BWaxes.XTick = [];
            app.BWaxes.YTick = [];
            app.OpenBut = uibutton(app.ImagesUIFigure, 'push');
            app.OpenBut.ButtonPushedFcn = createCallbackFcn(app, @OpenButPushed, true);
            app.OpenBut.Text = 'Выбрать фото';
            app.histBut = uibutton(app.ImagesUIFigure, 'push');
            app.histBut.ButtonPushedFcn = createCallbackFcn(app, @histButPushed, true);
            app.histBut.Text = 'Гистограмма';
            app.OneImg1 = uieditfield(app.ImagesUIFigure, 'numeric');
            app.OneImg1.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.OneImg2 = uieditfield(app.ImagesUIFigure, 'numeric');
            app.OneImg2.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.OneImg2.Value = 15;
            app.Button_4 = uibutton(app.ImagesUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'Считать';
            app.Slider = uislider(app.ImagesUIFigure);
            app.Slider.Limits = [0 15];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider2 = uislider(app.ImagesUIFigure);
            app.Slider2.Limits = [0 15];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider2.Value = 15;
            app.IMaxes = uiaxes(app.ImagesUIFigure);
            title(app.IMaxes, 'Исходное изображение')
            app.IMaxes.XTick = [];
            app.IMaxes.YTick = [];
            app.Button_5 = uibutton(app.ImagesUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = 'Выделить область';
            app.Label = uilabel(app.ImagesUIFigure);
            app.Label.Text = 'Произведите расчет';
            app.Button_6 = uibutton(app.ImagesUIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '1 слой';
            app.Label_4 = uilabel(app.ImagesUIFigure);
            app.Label_4.Text = 'Обработка изображения';
            app.Label_5 = uilabel(app.ImagesUIFigure);
            app.Label_5.Text = 'Обработка серии изображений';
            app.ManyImg1 = uieditfield(app.ImagesUIFigure, 'text');
            app.ManyImg1.Value = '0.85';
            app.ManyImg2 = uieditfield(app.ImagesUIFigure, 'text');
            app.ManyImg2.Value = '15';
            app.Label_6 = uilabel(app.ImagesUIFigure);
            app.Label_6.Text = 'Отсечка:';
            app.Plane = uiaxes(app.ImagesUIFigure);
            app.Plane.XTick = [];
            app.Plane.YTick = [];
            app.Label_8 = uilabel(app.ImagesUIFigure);
            app.Label_8.Text = '';
            app.select = uislider(app.ImagesUIFigure);
            app.select.Limits = [1 256];
            app.select.MajorTicks = [1 11 21 31 41 51 61 71 81 91 101 111 121 131 141 151 161 171 181 191 201 211 221 231 241 256];
            app.select.ValueChangedFcn = createCallbackFcn(app, @selectValueChanged, true);
            app.select.ValueChangingFcn = createCallbackFcn(app, @selectValueChanging, true);
            app.select.MinorTicks = [];
            app.select.Value = 1;
            app.BWaxes_2 = uiaxes(app.ImagesUIFigure);
            title(app.BWaxes_2, 'Выделенные границы слоев')
            app.BWaxes_2.XTick = [];
            app.BWaxes_2.YTick = [];
            app.Gauss = uibutton(app.ImagesUIFigure, 'push');
            app.Gauss.ButtonPushedFcn = createCallbackFcn(app, @GaussButtonPushed, true);
            app.Gauss.Text = 'Фильтр Гаусса';
            app.median = uibutton(app.ImagesUIFigure, 'push');
            app.median.ButtonPushedFcn = createCallbackFcn(app, @medianButtonPushed, true);
            app.median.Text = 'Медианный фильтр';
            app.Button_7 = uibutton(app.ImagesUIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = 'Удалить фильтры';
            app.Button_8 = uibutton(app.ImagesUIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = 'Фильтр Собеля';
            app.Button_9 = uibutton(app.ImagesUIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = 'Размытие морфология';
            app.Button_11 = uibutton(app.ImagesUIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '2 слой';
            app.Label_9 = uilabel(app.ImagesUIFigure);
            app.Label_9.Text = 'Выбранное  изображение';
            app.Label_10 = uilabel(app.ImagesUIFigure);
            app.Label_10.Text = '';
            app.EditField = uieditfield(app.ImagesUIFigure, 'text');
            app.Button_12 = uibutton(app.ImagesUIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = 'Построить плоскость';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImagesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImagesUIFigure)
        end
    end
end